classdef CoronaData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CoronavirusDataLabel         matlab.ui.control.Label
        ConfirmedcasesCheckBox       matlab.ui.control.CheckBox
        ConfirmeddeathsCheckBox      matlab.ui.control.CheckBox
        ConfirmedrecoveriesCheckBox  matlab.ui.control.CheckBox
        PickwhatdatayouwantgraphedLabel  matlab.ui.control.Label
        PickthecountryLabel          matlab.ui.control.Label
        GenerategraphButton          matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        ListBox                      matlab.ui.control.ListBox
        Error                        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerategraphButton
        function GenerategraphButtonPushed(app, event)
            if app.ConfirmedcasesCheckBox.Value == 0 && app.ConfirmeddeathsCheckBox.Value == 0 && app.ConfirmedrecoveriesCheckBox.Value == 0
                app.Error.Text = ['Please select what type of data ' ...
                    'you want to graph']
            else
                app.Error.Text = ''
                if app.ConfirmedcasesCheckBox.Value == 1
                    CountriesConfirmed
                    x=table2array(CountriesConfirmedData(1:end,1));
                    y=(strfind(x,app.ListBox.Value));
                    y=~cellfun(@isempty,y);
                    row = find(y,1);
                    y=table2cell(CountriesConfirmedData(row,2:end))
                    y=cell2mat(y);
                    x=0:(length(y)-1);
                    figure("Name",app.ListBox.Value)
                    semilogy(x,y)
                    xlabel('Days since January 22, 2020')
                    ylabel('Confirmed cases')
                    str="Confirmed cases for " + string(app.ListBox.Value)
                    title(str)
                end
                if app.ConfirmeddeathsCheckBox.Value == 1
                    CountriesDeaths
                    x=table2array(CountriesDeathsData(1:end,1));
                    y=(strfind(x,app.ListBox.Value));
                    y=~cellfun(@isempty,y);
                    row = find(y,1);
                    y=table2cell(CountriesDeathsData(row,2:end))
                    y=cell2mat(y);
                    x=0:(length(y)-1);
                    figure("Name",app.ListBox.Value)
                    semilogy(x,y)
                    xlabel('Days since January 22, 2020')
                    ylabel('Confirmed deaths')
                    str="Confirmed deaths for " + string(app.ListBox.Value)
                    title(str)
                end
                if app.ConfirmedrecoveriesCheckBox.Value == 1
                    CountriesRecovered
                    x=table2array(CountriesRecoveredData(1:end,1));
                    y=(strfind(x,app.ListBox.Value));
                    y=~cellfun(@isempty,y);
                    row = find(y,1);
                    y=table2cell(CountriesRecoveredData(row,2:end))
                    y=cell2mat(y);
                    x=0:(length(y)-1);
                    figure("Name",app.ListBox.Value)
                    semilogy(x,y)
                    xlabel('Days since January 22, 2020')
                    ylabel('Confirmed recoveries')
                    str="Confirmed recoveries for " + string(app.ListBox.Value)
                    title(str)
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CoronavirusDataLabel = uilabel(app.UIFigure);
            app.CoronavirusDataLabel.Text = 'Coronavirus Data';
            app.ConfirmedcasesCheckBox = uicheckbox(app.UIFigure);
            app.ConfirmedcasesCheckBox.Text = 'Confirmed cases';
            app.ConfirmeddeathsCheckBox = uicheckbox(app.UIFigure);
            app.ConfirmeddeathsCheckBox.Text = 'Confirmed deaths';
            app.ConfirmedrecoveriesCheckBox = uicheckbox(app.UIFigure);
            app.ConfirmedrecoveriesCheckBox.Text = 'Confirmed recoveries';
            app.PickwhatdatayouwantgraphedLabel = uilabel(app.UIFigure);
            app.PickwhatdatayouwantgraphedLabel.Text = '1. Pick what data you want graphed';
            app.PickthecountryLabel = uilabel(app.UIFigure);
            app.PickthecountryLabel.Text = '2. Pick the country';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Aruba', 'Antigua and Barbuda', 'Afghanistan', 'Algeria', 'Azerbaijan', 'Albania', 'Armenia', 'Andorra', 'Angola', 'American Samoa', 'Argentina', 'Australia', 'Austria', 'Anguilla', 'Antarctica', 'Bahrain', 'Barbados', 'Botswana', 'Bermuda', 'Belgium', 'Bahamas', ' The', 'Bangladesh', 'Belize', 'Bosnia and Herzegovina', 'Bolivia', 'Myanmar (Burma)', 'Benin', 'Byelarus', 'Solomon Islands', 'Brazil', 'Bhutan', 'Bulgaria', 'Bouvet Island', 'Brunei', 'Burundi', 'Canada', 'Cambodia', 'Chad', 'Sri Lanka', 'Congo', 'Zaire', 'China', 'Chile', 'Cayman Islands', 'Cocos (Keeling) Islands', 'Cameroon', 'Comoros', 'Colombia', 'Northern Mariana Islands', 'Costa Rica', 'Central African Republic', 'Cuba', 'Cape Verde', 'Cook Islands', 'Cyprus', 'Denmark', 'Djibouti', 'Dominica', 'Jarvis Island', 'Dominican Republic', 'Ecuador', 'Egypt', 'Ireland', 'Equatorial Guinea', 'Estonia', 'Eritrea', 'El Salvador', 'Ethiopia', 'Czech Republic', 'French Guiana', 'Finland', 'Fiji', 'Falkland Islands (Islas Malvinas)', 'Federated States of Micronesia', 'Faroe Islands', 'French Polynesia', 'Baker Island', 'France', 'French Southern & Antarctic Lands', 'Gambia', ' The', 'Gabon', 'Georgia', 'Ghana', 'Gibraltar', 'Grenada', 'Guernsey', 'Greenland', 'Germany', 'Glorioso Islands', 'Guadeloupe', 'Guam', 'Greece', 'Guatemala', 'Guinea', 'Guyana', 'Gaza Strip', 'Haiti', 'Heard Island & McDonald Islands', 'Honduras', 'Howland Island', 'Croatia', 'Hungary', 'Iceland', 'Indonesia', 'Man', ' Isle of', 'India', 'British Indian Ocean Territory', 'Iran', 'Israel', 'Italy', 'Ivory Coast', 'Iraq', 'Japan', 'Jersey', 'Jamaica', 'Jan Mayen', 'Jordan', 'Johnston Atoll', 'Juan De Nova Island', 'Kenya', 'Kyrgyzstan', 'North Korea', 'Kiribati', 'South Korea', 'Christmas Island', 'Kuwait', 'Kazakhstan', 'Laos', 'Lebanon', 'Latvia', 'Lithuania', 'Liberia', 'Slovakia', 'Liechtenstein', 'Lesotho', 'Luxembourg', 'Libya', 'Madagascar', 'Martinique', 'Macau', 'Moldova', 'Mayotte', 'Mongolia', 'Montserrat', 'Malawi', 'Macedonia', 'Mali', 'Monaco', 'Morocco', 'Mauritius', 'Midway Islands', 'Mauritania', 'Malta', 'Oman', 'Maldives', 'Montenegro', 'Mexico', 'Malaysia', 'Mozambique', 'New Caledonia', 'Niue', 'Norfolk Island', 'Niger', 'Vanuatu', 'Nigeria', 'Netherlands', 'Norway', 'Nepal', 'Nauru', 'Suriname', 'Netherlands Antilles', 'Nicaragua', 'New Zealand', 'Paraguay', 'Pitcairn Islands', 'Peru', 'Paracel Islands', 'Spratly Islands', 'Pakistan', 'Poland', 'Panama', 'Portugal', 'Papua New Guinea', 'Pacific Islands (Palau)', 'Guinea-Bissau', 'Qatar', 'Reunion', 'Marshall Islands', 'Romania', 'Philippines', 'Puerto Rico', 'Russia', 'Rwanda', 'Saudi Arabia', 'St. Pierre and Miquelon', 'St. Kitts and Nevis', 'Seychelles', 'South Africa', 'Senegal', 'St. Helena', 'Slovenia', 'Sierra Leone', 'San Marino', 'Singapore', 'Somalia', 'Spain', 'Serbia', 'St. Lucia', 'Sudan', 'Svalbard', 'Sweden', 'South Georgia and the South Sandwich Is', 'Syria', 'Switzerland', 'United Arab Emirates', 'Trinidad and Tobago', 'Thailand', 'Tajikistan', 'Turks and Caicos Islands', 'Tokelau', 'Tonga', 'Togo', 'Sao Tome and Principe', 'Tunisia', 'Turkey', 'Tuvalu', 'Taiwan', 'Turkmenistan', 'Tanzania', ' United Republic of', 'Uganda', 'United Kingdom', 'Ukraine', 'United States', 'Burkina Faso', 'Uruguay', 'Uzbekistan', 'St. Vincent and the Grenadines', 'Venezuela', 'British Virgin Islands', 'Vietnam', 'Virgin Islands', 'Namibia', 'West Bank', 'Wallis and Futuna', 'Western Sahara', 'Wake Island', 'Western Samoa', 'Swaziland', 'Yemen', 'Zambia', 'Zimbabwe'};
            app.ListBox.Value = 'Aruba';
            app.GenerategraphButton = uibutton(app.UIFigure, 'push');
            app.GenerategraphButton.ButtonPushedFcn = createCallbackFcn(app, @GenerategraphButtonPushed, true);
            app.GenerategraphButton.Text = {'Generate graph'; ''};
            app.Error = uilabel(app.UIFigure);
            app.Error.Text = {''; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CoronaData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end